classdef garNET_explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        garNET_explorer_GUI     matlab.ui.Figure
        garNETExplorerMenu      matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        NewSessionMenu          matlab.ui.container.Menu
        QuitMenu                matlab.ui.container.Menu
        FileMenu                matlab.ui.container.Menu
        LoadFolderMenu          matlab.ui.container.Menu
        LoadGrainsMenu          matlab.ui.container.Menu
        CheckMenu               matlab.ui.container.Menu
        PlotFolderMenu          matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        VersionXXXLabel         matlab.ui.control.Label
        garNETexplorerLabel     matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        OptionsPanel            matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        Show3DviewerCheckBox    matlab.ui.control.CheckBox
        DetailedsliderCheckBox  matlab.ui.control.CheckBox
        ClassificationPanel     matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        EditField2              matlab.ui.control.EditField
        OtherButton             matlab.ui.control.Button
        NotGarnetButton         matlab.ui.control.Button
        SkipButton              matlab.ui.control.Button
        MultipleButton          matlab.ui.control.Button
        PittingButton           matlab.ui.control.Button
        AtollButton             matlab.ui.control.Button
        IntactButton            matlab.ui.control.Button
        Slider                  matlab.ui.control.Slider
        ViewDropDown            matlab.ui.control.DropDown
        ViewDropDownLabel       matlab.ui.control.Label
        SlideId                 matlab.ui.control.NumericEditField
        ImageIDLabel            matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        Tree                    matlab.ui.container.Tree
        viewer_3D               matlab.ui.container.Panel
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        version

        GrainData       % Description
        Directories

        WaitBar
        Starting     
        Starting4Volume
        LastPosition % Description
        GrainCounter % Description
        Tstart

        VolumeOject
        RGBImageObject
    end
    
    methods (Access = private)
        
        function loadGrains(app,filename,pathname)
            
            Nb = length(app.GrainData.Names);

            app.Directories.Source = pathname;

            for i = 1:length(filename)
                app.GrainData.Names{Nb+i} = filename{i};
                app.GrainData.Data(Nb+i).Data = readNPY(fullfile(pathname,filename{i}));

                p = uitreenode(app.Tree,'Text',char(app.GrainData.Names{Nb+i}),'NodeData',[Nb+i]);
                
                % disp(['Writing',num2str(i)])
            end

        end
        
        function PlotGrain(app,Idx)

            SelectedGrain = app.Tree.SelectedNodes.NodeData;
            
            if Idx > 0
                SliderValue = round(Idx);
            else
                SliderValue = round(app.Slider.Value);
            end

            if isequal(app.Starting,1)
                app.Starting = 0;

                % default is z
                imagesc(app.UIAxes,squeeze(app.GrainData.Data(SelectedGrain).Data(SliderValue,:,:)));

            else
                switch app.ViewDropDown.Value

                    case 'X-view'

                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(:,SliderValue,:));

%                         if Idx > 0
%                             app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(:,SliderValue,:));
%                         else
%                             imagesc(app.UIAxes,squeeze(app.GrainData.Data(SelectedGrain).Data(:,SliderValue,:)));
%                         end

                    case 'Y-view'
                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(:,:,SliderValue));

                    case 'Z-view'
                        app.UIAxes.Children.CData = squeeze(app.GrainData.Data(SelectedGrain).Data(SliderValue,:,:));

                end
            end

            app.SlideId.Value = SliderValue;

            colormap(app.UIAxes,"gray")
            axis(app.UIAxes,'image');
            
            if Idx > 0 && isequal(app.DetailedsliderCheckBox.Value,0)
                if abs(Idx-app.LastPosition) > 3
                    drawnow
                    app.LastPosition = Idx;
                end
            else
                drawnow
            end
            
            %keyboard
            
        end
        
        function SetSliderLimitsAuto(app)

            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            switch app.ViewDropDown.Value

                case 'X-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,2)];

                case 'Y-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,3)];

                case 'Z-view'
                    app.Slider.Limits = [1,size(app.GrainData.Data(SelectedGrain).Data,1)];

            end

            app.Slider.Value = round((app.Slider.Limits(2)-1)/2);

        end
        
        function Display3Dview(app)
            
            %cla(app.viewer_3D)
            %reset(app.UIAxes_2)
            
            %cla(app.UIAxes_2,'reset')
            
            %delete(app.viewer_3D);

            %app.viewer_3D = uipanel(app.GridLayout);
            %app.viewer_3D.Layout.Row = [1 12];
            %app.viewer_3D.Layout.Column = [23 33];

            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            if app.Show3DviewerCheckBox.Value
                if isequal(app.Starting4Volume,1)
                    app.VolumeOject = volshow(app.GrainData.Data(SelectedGrain).Data, 'parent',app.viewer_3D,'Colormap',copper(256));
                else
                    %setVolume(app.VolumeOject,app.GrainData.Data(SelectedGrain).Data);
                    
                    % probably not compatible with MATLAB R2022b
                    % for new releases:
                    app.VolumeOject.Data = app.GrainData.Data(SelectedGrain).Data;

                end
            end


            R = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,1));
            G = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,2));
            B = squeeze(sum(app.GrainData.Data(SelectedGrain).Data,3));
            
            R = imresize(R,[64,64]);
            G = imresize(G,[64,64]);
            B = imresize(B,[64,64]);

            R = rescale(R,0,1);
            G = rescale(G,0,1);
            B = rescale(B,0,1);

            rgbimg=cat(3,R,G,B);
            if isequal(app.Starting4Volume,1)
                app.RGBImageObject = imshow(rgbimg,'Parent',app.UIAxes_2);
                app.Starting4Volume = 0;

            else
                app.RGBImageObject.CData = rgbimg;
            end

        end
        
        function TakeCareOfThisFella(app,FolderName)
            app.WaitBar = uiprogressdlg(app.garNET_explorer_GUI);
            %
            SelectedGrain = app.Tree.SelectedNodes.NodeData;

            if ~exist(fullfile(app.Directories.Working,'train_data',FolderName))
                [Success,Message,MessageID] = mkdir(fullfile(app.Directories.Working,'train_data',FolderName));
            end

            h = copyfile(fullfile(app.Directories.Source,app.GrainData.Names{SelectedGrain}),fullfile(app.Directories.Working,'train_data',FolderName,app.GrainData.Names{SelectedGrain}),'f');

            disp([app.GrainData.Names{SelectedGrain},' ',FolderName])
            dlmwrite(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),[app.GrainData.Names{SelectedGrain},' ',FolderName],'-append','delimiter','')

%             f2 = figure;
%             ax2 = gca;
%             copyobj(app.UIAxes_2.Children,ax2);
% 
%             saveas(f2,fullfile(app.Directories.Working,'train_data',[FolderName,app.GrainData.Names{SelectedGrain},'.png']))
%             close(f2)

            app.GrainData.Names(SelectedGrain) = [];
            app.GrainData.Data(SelectedGrain) = [];

            app.Tree.Children(SelectedGrain).delete;

            for i = 1:length(app.Tree.Children)
                app.Tree.Children(i).NodeData = i;
            end

            if SelectedGrain <= length(app.GrainData.Names)
                app.Tree.SelectedNodes = app.Tree.Children(SelectedGrain);
                SetSliderLimitsAuto(app);
                PlotGrain(app,0);
                Display3Dview(app);
            end
            close(app.WaitBar)

            app.GrainCounter = app.GrainCounter + 1;
            telapsed = toc(app.Tstart);

            Average = telapsed/app.GrainCounter;

            if Average > 30
                app.ResultLabel.Text = [num2str(app.GrainCounter),' grains classified (',num2str(round(Average)),' seconds per grain)',' Thanks a lot for your contribution!'];
            else
                app.ResultLabel.Text = [num2str(app.GrainCounter),' grains classified (',num2str(round(Average)),' seconds per grain)',' Excellent speed!'];
            end


        end
        
        function LoadSelectedFiles(app,filename,pathname)

            ClassifiedGrains = '';
            Count = 0;
            fid = fopen(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),'r');
            while 1
                TheLine = fgetl(fid);
                if ~isequal(TheLine,-1)
                    TheLineStr = strread(TheLine,'%s');
                    Count = Count + 1;
                    ClassifiedGrains{Count} = TheLineStr{1};
                else
                    break
                end
            end
            fclose(fid);

            Eliminate = zeros(size(filename));
            for i = 1:length(filename)
                IsClassified = find(ismember(ClassifiedGrains,filename{i}));
                if IsClassified
                    Eliminate(i) = 1;
                end
            end
            
            filename(find(Eliminate)) = [];

            loadGrains(app,filename,pathname);

            app.Tree.SelectedNodes = app.Tree.Children(1);

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Version

            app.version = '1.0 (230428)';
            app.VersionXXXLabel.Text = ['version ',app.version];

            app.garNET_explorer_GUI.Visible = 'off';

            if isdeployed
                f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
                Directory = uigetdir(cd, 'Select a working directory');
                close(f);
                figure(app.garNET_explorer_GUI);
                if ~isempty(Directory)
                    cd(Directory);
                end
            end

            app.Directories.Working = cd;

            app.garNET_explorer_GUI.Name = ['garNET_explorer ',app.version(1:3),' - ',app.Directories.Working];

            app.GrainData.Names = {};
            app.GrainData.Data(1).Data = [];
            

            [Success,Message,MessageID] = mkdir('train_data');
            cd train_data/
            [Success,Message,MessageID] = mkdir('intact');
            [Success,Message,MessageID] = mkdir('atoll');
            [Success,Message,MessageID] = mkdir('pitting');
            [Success,Message,MessageID] = mkdir('multiple');
            [Success,Message,MessageID] = mkdir('notGarnet');
            cd ..

            % keyboard
            clc

            if ~exist(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'))
                dlmwrite(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),[]);
            end

            app.Starting = 1;
            app.Starting4Volume = 1;
            app.LastPosition = 0;
            app.GrainCounter = 0;
            app.Tstart = tic;

            movegui(app.garNET_explorer_GUI,"center");

            app.garNET_explorer_GUI.Visible = 'on';


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.WaitBar = uiprogressdlg(app.garNET_explorer_GUI);
            
            selectedNodes = app.Tree.SelectedNodes;

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);

            close(app.WaitBar)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            PlotGrain(app,0);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            PlotGrain(app,changingValue);
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
        end

        % Button pushed function: IntactButton
        function IntactButtonPushed(app, event)
            TakeCareOfThisFella(app,'intact');
        end

        % Button pushed function: AtollButton
        function AtollButtonPushed(app, event)
            TakeCareOfThisFella(app,'atoll');
        end

        % Button pushed function: PittingButton
        function PittingButtonPushed(app, event)
            TakeCareOfThisFella(app,'pitting');
        end

        % Button pushed function: MultipleButton
        function MultipleButtonPushed(app, event)
            TakeCareOfThisFella(app,'multiple');
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            SelectedGrain = app.Tree.SelectedNodes.NodeData;
            if SelectedGrain + 1 <= length(app.GrainData.Names)
                app.Tree.SelectedNodes = app.Tree.Children(app.Tree.SelectedNodes.NodeData + 1);
                SetSliderLimitsAuto(app);
                PlotGrain(app,0);
                Display3Dview(app);
            end
        end

        % Button pushed function: OtherButton
        function OtherButtonPushed(app, event)
            TakeCareOfThisFella(app,app.EditField2.Value);
        end

        % Button pushed function: NotGarnetButton
        function NotGarnetButtonPushed(app, event)
            TakeCareOfThisFella(app,'notGarnet');
        end

        % Close request function: garNET_explorer_GUI
        function garNET_explorer_GUICloseRequest(app, event)
            
            delete(app)
            
        end

        % Menu selected function: LoadFolderMenu
        function LoadFolderMenuSelected(app, event)

            pathname = fullfile(app.Directories.Working,'grain_arrays');

            if exist(pathname)
                if ispc
                    DataNames = dir('grain_arrays\*.npy');
                else
                    DataNames = dir('grain_arrays/*.npy');
                end
            end

            NbFiles = length(DataNames);

            prompt={'How many grains?'};
            name='Input';
            numlines=1;
            defaultanswer={num2str(NbFiles)};

            answer=inputdlg(prompt,name,numlines,defaultanswer);

            if isempty(answer)
                return
            end

            Nb = str2num(answer{1});

            p = randperm(NbFiles,Nb);

            filename = cell(Nb,1);

            for i = 1:Nb
                filename{i} = DataNames(p(i)).name;
            end


            LoadSelectedFiles(app,filename,pathname)
        end

        % Menu selected function: LoadGrainsMenu
        function LoadGrainsMenuSelected(app, event)

            %
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            [filename, pathname] = uigetfile('*.npy', 'Pick files','MultiSelect','on');
            delete(f);
            figure(app.garNET_explorer_GUI)
            if isequal(filename,0)
                return
            end

            %LoadSelectedFiles(app,filename,pathname);

            ClassifiedGrains = '';
            Count = 0;
            fid = fopen(fullfile(app.Directories.Working,'train_data','log_classified_grains.txt'),'r');
            while 1
                TheLine = fgetl(fid);
                if ~isequal(TheLine,-1)
                    TheLineStr = strread(TheLine,'%s');
                    Count = Count + 1;
                    ClassifiedGrains{Count} = TheLineStr{1};
                else
                    break
                end
            end
            fclose(fid);

            Eliminate = zeros(size(filename));
            if ~iscell(filename)
                TEMP = filename;
                clear filename
                filename{1} = TEMP;
            end
            for i = 1:length(filename)
                IsClassified = find(ismember(ClassifiedGrains,filename{i}));
                if IsClassified
                    Eliminate(i) = 1;
                end
            end

            filename(find(Eliminate)) = [];

            loadGrains(app,filename,pathname);

            app.Tree.SelectedNodes = app.Tree.Children(1);

            SetSliderLimitsAuto(app);
            PlotGrain(app,0);
            Display3Dview(app);

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uialert(app.garNET_explorer_GUI, {'Developped by P. Lanari & P. Hartmeier ','Institute of Geological Sciences, University of Bern'}, 'garNET_explorer','Icon','info');
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: NewSessionMenu
        function NewSessionMenuSelected(app, event)
            
            delete(app);

            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            Directory = uigetdir(cd, 'Select a working directory');
            close(f);
            if ~isempty(Directory)
                cd(Directory);
            end
            
            garNET_explorer;

        end

        % Menu selected function: PlotFolderMenu
        function PlotFolderMenuSelected(app, event)
            
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            Directory = uigetdir(cd, 'Select a folder to plot grains data');
            close(f);
            if isempty(Directory)
                return
            end

            if ispc
                DataNames = dir([Directory,'\*.npy']);
            else
                DataNames = dir([Directory,'/*.npy']);
            end

            
            MaxPlot = 90;
            CountPlot = 0;
            CountFig = 1;

            app.WaitBar = uiprogressdlg(app.garNET_explorer_GUI,'Indeterminate','on','Message','Generating Figures','Title','garNET Explorer');

            f = figure;
            tiledlayout('flow','TileSpacing','none');
            for i = 1:length(DataNames)
                Data = readNPY(fullfile(Directory,DataNames(i).name));
                
                R = squeeze(sum(Data,1));
                G = squeeze(sum(Data,2));
                B = squeeze(sum(Data,3));

                R = imresize(R,[64,64]);
                G = imresize(G,[64,64]);
                B = imresize(B,[64,64]);

                R = rescale(R,0,1);
                G = rescale(G,0,1);
                B = rescale(B,0,1);

                rgbimg=cat(3,R,G,B);

                CountPlot = CountPlot + 1;
                if CountPlot >  MaxPlot
                    CountPlot = 0;
                    CountFig = CountFig + 1;
                    drawnow
                    f = figure;
                    tiledlayout('flow','TileSpacing','none');
                end

                nt = nexttile;
                imshow(rgbimg,'Parent',nt);
                text(nt,1,62,DataNames(i).name,'Color','w','FontSize',10,'Interpreter','none')
            end
            
            close(app.WaitBar)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.garNET_explorer_GUI = uifigure('Visible', 'off');
            app.garNET_explorer_GUI.Name = 'garNET_explorer';
            app.garNET_explorer_GUI.CloseRequestFcn = createCallbackFcn(app, @garNET_explorer_GUICloseRequest, true);
            app.garNETExplorerMenu = uimenu(app.garNET_explorer_GUI);
            app.garNETExplorerMenu.Text = 'garNET Explorer';
            app.AboutMenu = uimenu(app.garNETExplorerMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About ...';
            app.NewSessionMenu = uimenu(app.garNETExplorerMenu);
            app.NewSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewSessionMenuSelected, true);
            app.NewSessionMenu.Text = 'New Session';
            app.QuitMenu = uimenu(app.garNETExplorerMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.FileMenu = uimenu(app.garNET_explorer_GUI);
            app.FileMenu.Text = 'File';
            app.LoadFolderMenu = uimenu(app.FileMenu);
            app.LoadFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFolderMenuSelected, true);
            app.LoadFolderMenu.Text = 'Load Folder';
            app.LoadGrainsMenu = uimenu(app.FileMenu);
            app.LoadGrainsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGrainsMenuSelected, true);
            app.LoadGrainsMenu.Text = 'Load Grain(s)';
            app.CheckMenu = uimenu(app.garNET_explorer_GUI);
            app.CheckMenu.Text = 'Check';
            app.PlotFolderMenu = uimenu(app.CheckMenu);
            app.PlotFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotFolderMenuSelected, true);
            app.PlotFolderMenu.Text = 'Plot Folder';
            app.GridLayout = uigridlayout(app.garNET_explorer_GUI);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.viewer_3D = uipanel(app.GridLayout);
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ResultLabel = uilabel(app.GridLayout);
            app.ResultLabel.Text = 'Thanks for your help!';
            app.ClassificationPanel = uipanel(app.GridLayout);
            app.ClassificationPanel.Title = 'Classification';
            app.GridLayout2 = uigridlayout(app.ClassificationPanel);
            app.ImageIDLabel = uilabel(app.GridLayout2);
            app.ImageIDLabel.Text = 'Image ID';
            app.SlideId = uieditfield(app.GridLayout2, 'numeric');
            app.ViewDropDownLabel = uilabel(app.GridLayout2);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout2);
            app.ViewDropDown.Items = {'X-view', 'Y-view', 'Z-view'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Z-view';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.Limits = [1 100000];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.IntactButton = uibutton(app.GridLayout2, 'push');
            app.IntactButton.ButtonPushedFcn = createCallbackFcn(app, @IntactButtonPushed, true);
            app.IntactButton.Text = 'Intact';
            app.AtollButton = uibutton(app.GridLayout2, 'push');
            app.AtollButton.ButtonPushedFcn = createCallbackFcn(app, @AtollButtonPushed, true);
            app.AtollButton.Text = 'Atoll';
            app.PittingButton = uibutton(app.GridLayout2, 'push');
            app.PittingButton.ButtonPushedFcn = createCallbackFcn(app, @PittingButtonPushed, true);
            app.PittingButton.Text = 'Pitting';
            app.MultipleButton = uibutton(app.GridLayout2, 'push');
            app.MultipleButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleButtonPushed, true);
            app.MultipleButton.Text = 'Multiple';
            app.SkipButton = uibutton(app.GridLayout2, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.NotGarnetButton = uibutton(app.GridLayout2, 'push');
            app.NotGarnetButton.ButtonPushedFcn = createCallbackFcn(app, @NotGarnetButtonPushed, true);
            app.NotGarnetButton.Text = 'Not Garnet';
            app.OtherButton = uibutton(app.GridLayout2, 'push');
            app.OtherButton.ButtonPushedFcn = createCallbackFcn(app, @OtherButtonPushed, true);
            app.OtherButton.Text = 'Other';
            app.EditField2 = uieditfield(app.GridLayout2, 'text');
            app.EditField2.Value = 'MyClass';
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout4 = uigridlayout(app.OptionsPanel);
            app.DetailedsliderCheckBox = uicheckbox(app.GridLayout4);
            app.DetailedsliderCheckBox.Text = 'Detailed slider';
            app.Show3DviewerCheckBox = uicheckbox(app.GridLayout4);
            app.Show3DviewerCheckBox.Text = 'Show 3D viewer';
            app.Show3DviewerCheckBox.Value = true;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dev', 'garNET_explorer.png');
            app.garNETexplorerLabel = uilabel(app.GridLayout);
            app.garNETexplorerLabel.Text = {'garNET'; 'explorer'};
            app.VersionXXXLabel = uilabel(app.GridLayout);
            app.VersionXXXLabel.Text = 'Version XXX';
            % Show the figure after all components are created
            app.garNET_explorer_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = garNET_explorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.garNET_explorer_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.garNET_explorer_GUI)
        end
    end
end